classdef EEGmotifs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGmotifsUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SelectLoadingButton             matlab.ui.control.Button
        SelectSavingButton              matlab.ui.control.Button
        SelectsourcefolderLabel         matlab.ui.control.Label
        SelectdestinationfolderLabel    matlab.ui.control.Label
        LaunchButton                    matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        AnalysisTechniqueDropDownLabel  matlab.ui.control.Label
        AnalysisTechniqueDropDown       matlab.ui.control.DropDown
        NumberofmotifsDropDownLabel     matlab.ui.control.Label
        NumberMotifsDropDown            matlab.ui.control.DropDown
        FeedbackArea                    matlab.ui.control.TextArea
        SourceLamp                      matlab.ui.control.Lamp
        DestinationLamp                 matlab.ui.control.Lamp
    end


    properties (Access = private)
        parameters;
        information;
        loading_path = "";
        saving_path = "";
        motif_number = 0;
        isLoading = 0;
        isSaving = 0;
    end

    methods (Access = private)
    
        function [is_ready] = isReady(app)
            if(app.isSaving && app.isLoading)
               app.ReadyLamp.Color =  [0,1,0];
               is_ready = 1;
            else
                app.ReadyLamp.Color = [1,0,0];
                is_ready = 0;
            end
        end
        
        % Will update the content of the GUI 
        function update_gui(app)
            
            
        end
    
        
        % Will save the content of the GUI Parameters
        function save_gui(app)
            
            % Save the data
            app.parameters.general.is_dpli = selected(app,"analysis_technique","dPLI");
            app.parameters.general.is_nste = selected(app,"analysis_technique","NSTE");
            app.parameters.general.number_motifs = get_number_motifs(app);
            app.parameters.general.threshold = get_threshold(app);
            
             %Write to app_data
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
        
            % Refresh the GUI
        end
        
        function write(app,message)
            app.FeedbackArea.Value = message;
        end
    
        function refresh(app)
            % Fonctionality to have app on top of all windows
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';
            
            % Light up or down the Loading Lamp
            if(~strcmp(app.information.loading_path,""))
                app.SourceLamp.Color = [0,1,0];
            else
                app.SourceLamp.Color = [1,0,0];
            end
            
            % Light up or down the Saving Lamp
            if(~strcmp(app.information.saving_path,""))
                app.DestinationLamp.Color = [0,1,0];
            else
                app.DestionationLamp.Color = [1,0,0];
            end
        end
    
        function clean_up(app)
            %% Clean up folders if doesn't exist in this computer
            % Variables Setup
            loading_folder = app.information.loading_path;
            saving_folder = app.information.saving_path;
            
            % Reseting loading_path
            if(~exist(loading_folder, 'dir'))
                app.information.loading_path = "";
            end
            
            % Reseting saving_path
            if(~exist(saving_folder, 'dir'))
                app.information.saving_folder = "";
            end
        end
    
        %% Helper function (for clarity)
        function [selected] = is_selected(app,type,option)
            selected = 0;
            value = "";
            if(strcmp(type,"analysis_technique"))
               value = app.AnalysisTechniqueDropDown.Value;
            end
            selected = strcmp(value,option);
        end
    
        function [number_motifs] = get_number_motifs(app)
           number_motifs = str2num(app.NumberMotifsDropDown.Value); 
        end
    
        function [threshold] = get_threshold(app)
            threshold = app.ThresholdEditField.Value;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Here will need to load from app_data
             % Setup
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setting up Variables
            app_data = load(strcat(base_directory,strcat(filesep,'data',filesep,'app_data')));
            app.information = app_data.information;    
            app.parameters = app_data.parameters;
            
            % Updating computer specific variables
            app.information.base_directory = base_directory;
            
            % Cleanup
            clean_up(app)
            
            % Refresh the application
            refresh(app)
            
            % Write to feedback
            write(app,"EEGMotifs is ready.");
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Button pushed function: SelectLoadingButton
        function SelectLoadingButtonPushed(app, event)
            % Selecting a path
            selected_path = uigetdir;
            refresh(app);
            
            % Fetching variables:
            current_path = app.information.loading_path;
            % Updating the loading_path
            if isequal(selected_path,1)
               current_path = selected_path;
               app.information.loading_path = current_path;
            end
            
            % Write to feedback area
            write(app,strcat("Source folder: ",current_path));
            
            % Save information and parameters
            save_gui(app);
            
            % Update the GUI
            update_gui(app)
        end

        % Button pushed function: SelectSavingButton
        function SelectSavingButtonPushed(app, event)
            selected_path = uigetdir;
            refresh(app);
            
            % Fetching variables:
            current_path = app.information.saving_path;
            % Updating the saving_path
            if isequal(selected_path,1)
               current_path = selected_path;
               app.information.saving_path = current_path;
            end
            
            % Write to feedback area
            write(app,strcat("Destination folder: ",current_path));
            
            % Save information and parameters
            save_gui(app);
            
            % Update the GUI
            update_gui(app);
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            if(is_ready(app))
                
                if(app.Button_motif3.Value == 1)
                    app.motif_number = 3;
                else
                    app.motif_number = 4;
                end
                
                if(app.dPLIButton.Value == 1)
                    type = 'dpli';
                else
                    type = 'nste';
                end
                
                threshold = app.ThresholdEditField.Value;
                calculate_all_motifs(app.loading_path,type,threshold,app.motif_number,app.saving_path);
                app.isSaving = 0;
                app.isLoading = 0;
                isReady(app);
                disp('Motif analysis terminated');
            else
                disp('Please select a saving and a loading directory');
            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            save_gui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGmotifsUIFigure = uifigure;
            app.EEGmotifsUIFigure.Name = 'EEGmotifs';
            app.FileMenu = uimenu(app.EEGmotifsUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SelectLoadingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectLoadingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoadingButtonPushed, true);
            app.SelectLoadingButton.Text = 'Select';
            app.SelectSavingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectSavingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingButtonPushed, true);
            app.SelectSavingButton.Text = 'Select';
            app.SelectsourcefolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectsourcefolderLabel.Text = 'Select source folder';
            app.SelectdestinationfolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectdestinationfolderLabel.Text = 'Select destination folder';
            app.LaunchButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Enable = 'off';
            app.LaunchButton.Text = 'Launch';
            app.ThresholdEditFieldLabel = uilabel(app.EEGmotifsUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EEGmotifsUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.1;
            app.AnalysisTechniqueDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDownLabel.Text = 'Analysis Technique';
            app.AnalysisTechniqueDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDown.Items = {'dPLI', 'NSTE'};
            app.AnalysisTechniqueDropDown.Value = 'dPLI';
            app.NumberofmotifsDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.NumberofmotifsDropDownLabel.Text = 'Number of motifs';
            app.NumberMotifsDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.NumberMotifsDropDown.Items = {'3', '4'};
            app.NumberMotifsDropDown.Value = '3';
            app.FeedbackArea = uitextarea(app.EEGmotifsUIFigure);
            app.FeedbackArea.Editable = 'off';
            app.FeedbackArea.Value = {'Feedback Area'};
            app.SourceLamp = uilamp(app.EEGmotifsUIFigure);
            app.DestinationLamp = uilamp(app.EEGmotifsUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGmotifs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGmotifsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGmotifsUIFigure)
        end
    end
end